classdef Enter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageMagicUIFigure  matlab.ui.Figure
        Label_5             matlab.ui.control.Label
        ENTERButton         matlab.ui.control.StateButton
        Label_4             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        EditField_2         matlab.ui.control.EditField
        EditField           matlab.ui.control.EditField
        Label_6             matlab.ui.control.Label
        Label               matlab.ui.control.Label
        Image2              matlab.ui.control.Image
        Image               matlab.ui.control.Image
    end

    
    properties (Access = private)
        password % 密码
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.Label_3.Text = ' '; % 置空Label_3的文本
        end

        % Value changing function: EditField_2
        function EditField_2ValueChanging(app, event)
            changingValue = event.Value;

            app.Label_4.Text = ' ';  % 把"密码"提示删去
            
            % 保存密码
            if size(changingValue,2)>1 % 密码框中的字符多于1个
                if size(changingValue,2)-size(app.password,2) == 1     % 手动输入了1个字符
                    app.password = [app.password,changingValue(:,end)];
                elseif size(changingValue,2)-size(app.password,2) > 1  % 粘贴输入了多个字符
                    app.password = changingValue;
                else % 删除了字符
                    app.password = app.password(1,1:size(changingValue,2));
                end
            elseif size(changingValue,2) == 0 % 密码框中的字符为0个
                    app.password = [];
            elseif size(changingValue,2) == 1 % 密码框中的字符为1个
                    app.password = [app.password,changingValue(:,end)];
            end
            
            % 密码星号表示
            if size(changingValue,2)>1
                s = '*';
                for i = 2:size(changingValue,2)
                    s = [s,'*'];
                end
                app.EditField_2.Value = s;
                elseif size(changingValue,2)==1
                    app.EditField_2.Value = '*';
            end
        
        end

        % Value changed function: ENTERButton
        function ENTERButtonValueChanged(app, event)
            value = app.ENTERButton.Value;
            if strcmp(app.EditField.Value,'中山大学') && strcmp(app.password,'sysu1924')
                app.Label_5.FontColor = 'black';
                app.Label_5.Text = '登录成功';
                pause(0.5)
            else
                app.Label_5.FontColor = 'red';
                app.Label_5.Text = '账号或密码错误，请重试';
                pause(5)
                app.Label_5.Text = ' ';
            end
            run Main.mlapp
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ImageMagicUIFigure = uifigure('Visible', 'off');
            app.ImageMagicUIFigure.Name = 'ImageMagic';
            app.ImageMagicUIFigure.Icon = fullfile(pathToMLAPP, 'Logo.png');
            app.Image = uiimage(app.ImageMagicUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '封面图.jpg');
            app.Image2 = uiimage(app.ImageMagicUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '图标.png');
            app.Label = uilabel(app.ImageMagicUIFigure);
            app.Label.Text = 'ImageMagic';
            app.Label_6 = uilabel(app.ImageMagicUIFigure);
            app.Label_6.Text = '请登录您的软件';
            app.EditField = uieditfield(app.ImageMagicUIFigure, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.EditField_2 = uieditfield(app.ImageMagicUIFigure, 'text');
            app.EditField_2.ValueChangingFcn = createCallbackFcn(app, @EditField_2ValueChanging, true);
            app.Label_3 = uilabel(app.ImageMagicUIFigure);
            app.Label_3.Text = '账号';
            app.Label_4 = uilabel(app.ImageMagicUIFigure);
            app.Label_4.Text = '密码';
            app.ENTERButton = uibutton(app.ImageMagicUIFigure, 'state');
            app.ENTERButton.ValueChangedFcn = createCallbackFcn(app, @ENTERButtonValueChanged, true);
            app.ENTERButton.Text = 'ENTER';
            app.Label_5 = uilabel(app.ImageMagicUIFigure);
            app.Label_5.Text = '';
            % Show the figure after all components are created
            app.ImageMagicUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Enter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageMagicUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageMagicUIFigure)
        end
    end
end