classdef innovate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InnovativeFunctionsUIFigure  matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        PushTool2                    matlab.ui.container.toolbar.PushTool
        PushTool3                    matlab.ui.container.toolbar.PushTool
        PushTool4                    matlab.ui.container.toolbar.PushTool
        FaceRecognitionButton        matlab.ui.control.Button
        ImageDefoggingButton         matlab.ui.control.Button
        CoinsCountButton             matlab.ui.control.Button
        RedApplesDetectionButton     matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image;
        Pic;
        upgradepicture;
        changedpicture;
        lastpicture; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            [filename,pathname] =uigetfile({'*.png';'*.jpg';'*.bmp';'*.*'},'打开图片');%png、jpg、bmp为图片格式，可以更改为其它格式；打开图片为对话框标题，也可更改；
            if isequal(filename,0)||isequal(pathname,0)%此时判断是否成功选择文件，如果没有则提示错误提示
                errordlg("没有选中文件","错误");
            else
                str=[pathname filename];%保存选择图片的完整的文件路径
                app.image=imread(str);%读取图片
            end
            %%
            imshow(app.image,'parent',app.UIAxes)%选择在坐标区1中显示
            app.changedpicture=app.image;
        end

        % Button pushed function: RedApplesDetectionButton
        function RedApplesDetectionButtonPushed(app, event)
            app.lastpicture=app.changedpicture;
            hsv=rgb2hsv(app.changedpicture);
            h=hsv(:,:,1);
            h(h>330/360)=0;
            training=h(:);
            startdata = [0;60/360;120/360;180/360;240/360;300/360];
            [IDX,C]= kmeans(training,6,'Start',startdata);
            idbw = (IDX == 1);
            template = reshape(idbw, size(h));%重新排列矩阵
            SE = strel('diamond',3);
            result=imopen(template,SE);
            imshow(result,'Parent',app.UIAxes2);
            app.changedpicture=result;
            app.Pic=result;
        end

        % Button pushed function: CoinsCountButton
        function CoinsCountButtonPushed(app, event)
            app.lastpicture=app.changedpicture;
            if ndims(app.changedpicture)==3
                I_gray=rgb2gray(app.changedpicture);
            end
            %转换为灰度图
            BW=I_gray>99;%二值化（重点为确定阈值）
            % 去除噪声
            I_gray1=imfill(BW,'holes');%填充空洞 消除噪点
            %imshow(BW,'Parent',app.UIAxes2);
            SE = strel('disk',4,4);
            %确定半径（此处半径可自行调整，主要应用于后面的膨胀和腐蚀中）
            SE1 = strel('octagon',9);
            %确定半径（此处半径可自行调整，主要应用于后面的膨胀和腐蚀中）
            I_gray2=imerode(I_gray1,SE);%腐蚀
            I_gray3=imdilate(I_gray2,SE);%膨胀
            I_gray4=imerode(I_gray3,SE1);%腐蚀
            imshow(I_gray4,'Parent',app.UIAxes2);
            %计算硬币数量
            [L,n] = bwlabel(I_gray4);%n为硬币数目
            S=sprintf('检测到的硬币个数为：%d',n);
            msgbox(S);%弹窗显示运行结果
            app.changedpicture=I_gray4;
            app.Pic=I_gray4;
        end

        % Button pushed function: ImageDefoggingButton
        function ImageDefoggingButtonPushed(app, event)
             w0=0.65;   %0.65  乘积因子用来保留一些雾，1时完全去雾
            t0=0.1;

            I=app.changedpicture;
            [h,w,s]=size(I);
            min_I=zeros(h,w);

            %下面取得暗影通道图像
            for i=1:h
                for j=1:w
                    dark_I(i,j)=min(I(i,j,:));
                end
            end

            Max_dark_channel=double(max(max(dark_I)));  %天空亮度
            dark_channel=double(dark_I);
            t=1-w0*(dark_channel/Max_dark_channel);   %取得透谢分布率图

            T=uint8(t*255);
            t=max(t,t0);

            %%
            I1=double(I);
            J(:,:,1) = uint8((I1(:,:,1) - (1-t)*Max_dark_channel)./t);

            J(:,:,2) = uint8((I1(:,:,2) - (1-t)*Max_dark_channel)./t);

            J(:,:,3) =uint8((I1(:,:,3) - (1-t)*Max_dark_channel)./t);
            imshow(J,'Parent',app.UIAxes2);
            app.Pic=J;
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            [filename,pathname]=uiputfile({'*.jpg';'*.bmp';'*.tif';'*,png'},'Save Image','untitled.png');
            if ~isequal([filename,pathname],[0,0])
                Mypic=fullfile(path,filename);
                imwrite(app.Pic,Mypic);
                msgbox('Save Successfully');
            else
                msgbox('Fail to Save');
            end
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            choice=questdlg('Do you want to exit? ','Exit','Yes','No','No');
            switch choice
                case 'Yes'
                    delete(app.MorphologicalProcessingUIFigure);
                    return;
                case 'No'
                    return;
            end
        end

        % Clicked callback: PushTool4
        function PushTool4Clicked(app, event)
            run Main_new.mlapp
            delete(app)
        end

        % Button pushed function: FaceRecognitionButton
        function FaceRecognitionButtonPushed(app, event)
            i=app.changedpicture;
            I=rgb2gray(i);
            BW=im2bw(I);
            [n1 n2]=size(BW);
            r=floor(n1/10);
            c=floor(n2/10);
            x1=1;
            x2=r;
            s=r*c;
            for i=1:10
                y1=1;y2=c;
                for j=1:10
                    if(y2<=c|y2>=9*c)|(x1==1|x2==r*10)
                        loc=find(BW(x1:x2,y1:y2)==0);
                        [o p]=size(loc);
                        pr=o*100/s;
                        if pr<=100
                            BW(x1:x2,y1:y2)=0;
                            r1=x1;r2=x2;s1=y1;s2=y2;
                            pr1=0;
                        end
                    end
                       y1=y1+c;
                       y2=y2+c;
                end
             x1=x1+r;
             x2=x2+r;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.InnovativeFunctionsUIFigure = uifigure('Visible', 'off');
            app.InnovativeFunctionsUIFigure.Name = 'Innovative Functions';
            app.InnovativeFunctionsUIFigure.Icon = fullfile(pathToMLAPP, '创新标识符.png');
            app.Toolbar = uitoolbar(app.InnovativeFunctionsUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Import an Image'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, '导入.jpg');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Save'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, '保存.jpg');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Exit'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, '退出.jpg');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'Return to Upper level'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, '功能界面图标.png');
            app.UIAxes = uiaxes(app.InnovativeFunctionsUIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.InnovativeFunctionsUIFigure);
            title(app.UIAxes2, 'Processed Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.RedApplesDetectionButton = uibutton(app.InnovativeFunctionsUIFigure, 'push');
            app.RedApplesDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @RedApplesDetectionButtonPushed, true);
            app.RedApplesDetectionButton.Text = 'Red Apples Detection';
            app.CoinsCountButton = uibutton(app.InnovativeFunctionsUIFigure, 'push');
            app.CoinsCountButton.ButtonPushedFcn = createCallbackFcn(app, @CoinsCountButtonPushed, true);
            app.CoinsCountButton.Text = 'Coins Count';
            app.ImageDefoggingButton = uibutton(app.InnovativeFunctionsUIFigure, 'push');
            app.ImageDefoggingButton.ButtonPushedFcn = createCallbackFcn(app, @ImageDefoggingButtonPushed, true);
            app.ImageDefoggingButton.Text = 'Image Defogging';
            app.FaceRecognitionButton = uibutton(app.InnovativeFunctionsUIFigure, 'push');
            app.FaceRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @FaceRecognitionButtonPushed, true);
            app.FaceRecognitionButton.Text = 'Face Recognition';
            % Show the figure after all components are created
            app.InnovativeFunctionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = innovate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InnovativeFunctionsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InnovativeFunctionsUIFigure)
        end
    end
end