classdef lzl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageSegmentationUIFigure      matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        PushTool2                      matlab.ui.container.toolbar.PushTool
        PushTool3                      matlab.ui.container.toolbar.PushTool
        PushTool4                      matlab.ui.container.toolbar.PushTool
        ImageSegmentationButtonGroup   matlab.ui.container.ButtonGroup
        ThresholdSegementSpinner       matlab.ui.control.Spinner
        ThresholdSegementSpinnerLabel  matlab.ui.control.Label
        HoughTransformLineButton       matlab.ui.control.Button
        EdgeDetectionButtonGroup       matlab.ui.container.ButtonGroup
        CannyButton                    matlab.ui.control.Button
        PrewittButton                  matlab.ui.control.Button
        LOGButton                      matlab.ui.control.Button
        RobertsButton                  matlab.ui.control.Button
        SobelButton                    matlab.ui.control.Button
        HoughTransformCircleButton     matlab.ui.control.Button
        RegionalGrowthButton           matlab.ui.control.Button
        WatershedSegmentationButton    matlab.ui.control.Button
        TuningAreaButtonGroup          matlab.ui.container.ButtonGroup
        SpecularTransformationKnob     matlab.ui.control.DiscreteKnob
        SpecularTransformationKnobLabel  matlab.ui.control.Label
        AmbiguitySlider                matlab.ui.control.Slider
        Label_2                        matlab.ui.control.Label
        ContrastSpinner                matlab.ui.control.Spinner
        Label_3                        matlab.ui.control.Label
        RelativeBrightnessSpinner      matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image % Description
        upgradepicture;
        changedpicture;
        lastpicture;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WatershedSegmentationButton
        function WatershedSegmentationButtonPushed(app, event)
            I = im2gray (app.changedpicture) ;%转化为灰度图像
            hy = fspecial('sobel') ;%sobel算子
            hx = hy';
            Iy = imfilter(double(I),hy,'replicate');%滤波求y方向边缘
            Ix = imfilter(double(I),hx,'replicate');%滤波求x方向边缘
            gradmag = sqrt(Ix.^2 + Iy.^2);%求模
            L = watershed (gradmag) ;%直接应用分水岭算法
            Lrgb = label2rgb(L) ;%转化为彩色图像
            se = strel('disk', 20) ; %圆形结构元素
            Io = imopen(I,se) ;%形态学开操作
            Ie = imerode(I, se);%对图像进行腐蚀
            Iobr = imreconstruct(Ie,I) ;%形态学重建
            Ioc=imclose(Io,se);%形态学关操作
            Iobrd = imdilate(Iobr, se) ; %对图像进行膨胀
            Iobrcbr = imreconstruct(imcomplement (Iobrd),imcomplement(Iobr)) ;%形态学重建
            Iobrcbr = imcomplement(Iobrcbr);%图像求反
            fgm = imregionalmax(Iobrcbr) ; %局部极大值
            I2=I;
            I2(fgm) = 255;%局部极大值处像素值设置为255
            se2 = strel (ones(5,5));%结构元素
            fgm2 = imclose(fgm,se2);%关操作.
            fgm3 = imerode (fgm2, se2) ; %腐蚀
            fgm4 = bwareaopen (fgm3, 20) ;%开操作
            I3=I;
            I3 (fgm4) = 255;%前景处设置为255
            bw = im2bw (Iobrcbr, graythresh (Iobrcbr)) ; %转化为二值图像
            D = bwdist(bw) ;%计算距离
            DL = watershed(D) ;%分水岭变换
            bgm = DL == 0;%取分割边界
            gradmag2 = imimposemin (gradmag, bgm | fgm4) ;%设置最小值
            L = watershed(gradmag2) ; %分水岭变换
            I4 = I;
            I4(imdilate(L == 0,ones(3, 3)) | bgm | fgm4) = 255; %前景及边界处设置为255
            Lrgb = label2rgb(L,'jet','w','shuffle');%转化为伪彩色图像
            %set (Lrgb,'AlphaData', 0.3) ;
            %imshow(Lrgb);%显示伪彩色图像
            %title('Colored watershed label matrix')
            imshow(I,'Parent',app.UIAxes2) ;
            hold(app.UIAxes2,"on");
            himage=imshow(Lrgb,'parent',app.UIAxes2); %在原图上显示伪彩色图像
            set (himage,'AlphaData', 0.3) ;
        end

        % Button pushed function: RegionalGrowthButton
        function RegionalGrowthButtonPushed(app, event)
            I=app.changedpicture;
            if isinteger(I)
                I=im2double(I);
            end
            I = im2gray(I);


            imshow(I)
            [M,N]=size(I);
            [y,x]=getpts; %单击取点后，按enter结束
            x1=round(x);
            y1=round(y);
            seed=I(x1,y1); %获取中心像素灰度值

            J=zeros(M,N);
            J(x1,y1)=1;

            count=1; %待处理点个数
            threshold=0.15;
            while count>0
                count=0;
                for i=1:M %遍历整幅图像
                    for j=1:N
                        if J(i,j)==1 %点在“栈”内
                            if (i-1)>1&(i+1)<M&(j-1)>1&(j+1)<N %3*3邻域在图像范围内
                                for u=-1:1 %8-邻域生长
                                    for v=-1:1
                                        if J(i+u,j+v)==0&abs(I(i+u,j+v)-seed)<=threshold
                                            J(i+u,j+v)=1;
                                            count=count+1;  %记录此次新生长的点个数
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
            end
            imshow(J,'Parent',app.UIAxes2);
            app.changedpicture=J;
            app.lastpicture=app.changedpicture;
        end

        % Button pushed function: HoughTransformCircleButton
        function HoughTransformCircleButtonPushed(app, event)
            I= app.changedpicture;
            im=im2gray(I)
            %BW = edge(im,'sobel');
            [centers1, radii1, metric] = imfindcircles(im,[15 30]);
            %[centers2, radii2, metric] = imfindcircles(BW,[15 30]); 
            imshow(im,'Parent',app.UIAxes2)
            viscircles(app.UIAxes2,centers1, radii1,'EdgeColor','b');
            %imshow(BW)
            %viscircles(centers2, radii2,'EdgeColor','r');
            app.changedpicture=im;
            app.lastpicture=app.changedpicture;
        end

        % Value changed function: RelativeBrightnessSpinner
        function RelativeBrightnessSpinnerValueChanged(app, event)
            value = app.RelativeBrightnessSpinner.Value;
            if(value>100)
                app.RelativeBrightnessSpinner.Value=0;
                value=0;
                msgbox('超出范围','warning','help');
            end
            if isequal(app.image,'')
                msgbox('无图片','warning','help');
                return;
            elseif isequal(app.changedpicture,'')
                app.lastpicture=app.changedpicture;
                brightpicture=imadd(app.image,value);
                imshow(brightpicture,'Parent',app.UIAxes2);
                app.changedpicture=brightpicture;
            else
                app.lastpicture=app.changedpicture;
                brightpicture=imadd(app.changedpicture,value);
                imshow(brightpicture,'Parent',app.UIAxes2);
                app.changedpicture=brightpicture;
            end

        end

        % Value changing function: AmbiguitySlider
        function AmbiguitySliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue=double(changingValue);
            if isequal(app.image,'')
                msgbox('无图片','warning','help')
                return
            elseif isequal(app.changedpicture,'')
                app.lastpicture=app.upgradepicture;
                obscureway1=fspecial('motion',changingValue,changingValue); %有点问题，但是可以运行
                obscureway1=imfilter(app.image,obscureway1);
                app.upgradepicture=obscureway1;
                imshow(obscureway1,'Parent',app.UIAxes2);
                app.changedpicture=app.upgradepicture;
            else
                app.lastpicture=app.upgradepicture;
                obscureway1=fspecial('motion',changingValue,changingValue); %有点问题，但是可以运行
                obscureway1=imfilter(app.changedpicture,obscureway1);
                app.upgradepicture=obscureway1;
                imshow(obscureway1,'Parent',app.UIAxes2);
                app.changedpicture=app.upgradepicture;
            end
        end

        % Value changing function: ContrastSpinner
        function ContrastSpinnerValueChanging(app, event)
            changingValue = event.Value;
            changingValue=double(changingValue);
            changingValue=changingValue./100;
            if(changingValue>=0.5||changingValue<0)
                app.ContrastSpinner.Value=0;
                changingValue=0;
                msgbox('超出范围','warning','help');
            end
            if isequal(app.image,'')
                msgbox('无图片','warning','help');
                return
            elseif isequal(app.changedpicture,'')
                app.lastpicture=app.changedpicture;
                contrastpicture=imadjust(app.image,[changingValue,1-changingValue],[]);
                imshow(contrastpicture,'Parent',app.UIAxes2);
                app.changedpicture=contrastpicture;
                return
            else
                app.lastpicture=app.changedpicture;
                contrastpicture=imadjust(app.changedpicture,[changingValue,1-changingValue],[]);
                imshow(contrastpicture,'Parent',app.UIAxes2);
                app.changedpicture=contrastpicture;
            end
        end

        % Value changed function: SpecularTransformationKnob
        function SpecularTransformationKnobValueChanged(app, event)
            value = app.SpecularTransformationKnob.Value;
            app.lastpicture=app.changedpicture;
            if isequal(value,'X-axis')
                mirrorpicture=flip(app.changedpicture,1);
                imshow(mirrorpicture,'Parent',app.UIAxes2);
                app.changedpicture=mirrorpicture;
            elseif isequal(value,'Y-axis')
                mirrorpicture=flip(app.changedpicture,2);
                imshow(mirrorpicture,'Parent',app.UIAxes2);
                app.changedpicture=mirrorpicture;
            end
        end

        % Callback function
        function Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue=double(changingValue);
            app.lastpicture=app.changedpicture;
            anglepicture=imrotate(app.changedpicture,changingValue);
            imshow(anglepicture,'Parent',app.UIAxes2);
            app.changedpicture=anglepicture;
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            [filename,pathname] =uigetfile({'*.png';'*.jpg';'*.bmp';'*.*'},'打开图片');%png、jpg、bmp为图片格式，可以更改为其它格式；打开图片为对话框标题，也可更改；
            if isequal(filename,0)||isequal(pathname,0)%此时判断是否成功选择文件，如果没有则提示错误提示
                errordlg("没有选中文件","错误");
            else
                str=[pathname filename];%保存选择图片的完整的文件路径
                app.image=imread(str);%读取图片
            end
            %%
            imshow(app.image,'parent',app.UIAxes)%选择在坐标区1中显示
            app.changedpicture=app.image;
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            selection = questdlg('您确定要关闭应用程序吗？', ...
                '关闭应用程序', ...
                '是', '否', '是');
            switch selection
                case '是'
                    delete(app);
                case '否'
                    return
            end
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            %axesHandle = app.UIAxes2;  % 这里的 app 是您的 App Designer 应用程序对象名字
            [file, path]= uiputfile('*.jpg','Save Image as');
            %save=[path file];
            % 将Axes中的内容保存为图像文件
            %saveas(axesHandle, save,'jpg');  
            save=[path file]; imwrite(app.changedpicture,save,'jpg');
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            mysize=size(app.changedpicture);
            if numel(mysize)>2
                app.changedpicture=rgb2gray(app.changedpicture);
            end
            app.changedpicture=edge(app.changedpicture,'sobel');
            imshow(app.changedpicture,'Parent',app.UIAxes2);
        end

        % Button pushed function: RobertsButton
        function RobertsButtonPushed(app, event)
            mysize=size(app.changedpicture);
            if numel(mysize)>2
                app.changedpicture=rgb2gray(app.changedpicture);
            end
            app.changedpicture=edge(app.changedpicture,'roberts');
            imshow(app.changedpicture,'Parent',app.UIAxes2);
        end

        % Button pushed function: LOGButton
        function LOGButtonPushed(app, event)
            mysize=size(app.changedpicture);
            if numel(mysize)>2
                app.changedpicture=rgb2gray(app.changedpicture);
            end
            app.changedpicture=edge(app.changedpicture,'log');
            imshow(app.changedpicture,'Parent',app.UIAxes2);
        end

        % Button pushed function: CannyButton
        function CannyButtonPushed(app, event)
            mysize=size(app.changedpicture);
            if numel(mysize)>2
                app.changedpicture=rgb2gray(app.changedpicture);
            end
            app.changedpicture=edge(app.changedpicture,'canny');
            imshow(app.changedpicture,'Parent',app.UIAxes2);
        end

        % Button pushed function: HoughTransformLineButton
        function HoughTransformLineButtonPushed(app, event)
            img= app.changedpicture;
            im=im2gray(img);
            BW = edge(im,'sobel');
            [H, theta, rho] = hough(BW);
            P = houghpeaks(H,5);
            lines = houghlines(BW,theta,rho,P);
            %lines = houghlines(BW,theta,rho,P, 'FillGap', 50);
            imshow(im,'Parent',app.UIAxes2);hold(app.UIAxes2,"on");
            for k = 1:length(lines)
                xy = [lines(k).point1; lines(k).point2];
                plot(app.UIAxes2,xy(:,1),xy(:,2),'LineWidth',2,'Color','green');
            end
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            mysize=size(app.changedpicture);
            if numel(mysize)>2
                app.changedpicture=rgb2gray(app.changedpicture);
            end
            app.changedpicture=edge(app.changedpicture,"prewitt");
            imshow(app.changedpicture,'Parent',app.UIAxes2);
        end

        % Value changed function: ThresholdSegementSpinner
        function ThresholdSegementSpinnerValueChanged(app, event)
            value = app.ThresholdSegementSpinner.Value;
            if(value<0)
                app.RelativeBrightnessSpinner.Value=0;
                value=0;
                msgbox('超出范围','warning','help');
            end
            if isequal(app.image,'')
                msgbox('无图片','warning','help');
                return;
            elseif isequal(app.changedpicture,'')
                app.lastpicture=app.changedpicture;
                I=app.image;
                I=im2gray(I);
                T=value;
                [width,height]=size(I);
                for i=1:width
                    for j=1:height
                        if(I(i,j)<T)
                            BW1(i,j)=0;
                        else
                            BW1(i,j)=1;
                        end
                    end
                end
                imshow(BW1,'Parent',app.UIAxes2);
                app.changedpicture=BW1;
            else
                app.lastpicture=app.changedpicture;
                I=app.changedpicture;
                I=im2gray(I);
                T=value;
                [width,height]=size(I);
                for i=1:width
                    for j=1:height
                        if(I(i,j)<T)
                            BW1(i,j)=0;
                        else
                            BW1(i,j)=1;
                        end
                    end
                end
                imshow(BW1,'Parent',app.UIAxes2);
                app.changedpicture=BW1;
            end
        end

        % Clicked callback: PushTool4
        function PushTool4Clicked(app, event)
            run Main_new.mlapp
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ImageSegmentationUIFigure = uifigure('Visible', 'off');
            app.ImageSegmentationUIFigure.Name = 'Image Segmentation';
            app.ImageSegmentationUIFigure.Icon = fullfile(pathToMLAPP, 'lzl标识符.png');
            app.Toolbar = uitoolbar(app.ImageSegmentationUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, '导入.jpg');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, '保存.jpg');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, '退出.jpg');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, '功能界面图标.png');
            app.UIAxes = uiaxes(app.ImageSegmentationUIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.ImageSegmentationUIFigure);
            title(app.UIAxes2, 'Processed Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.TuningAreaButtonGroup = uibuttongroup(app.ImageSegmentationUIFigure);
            app.TuningAreaButtonGroup.Title = 'Tuning Area';
            app.Label = uilabel(app.TuningAreaButtonGroup);
            app.Label.Text = 'Relative Brightness';
            app.RelativeBrightnessSpinner = uispinner(app.TuningAreaButtonGroup);
            app.RelativeBrightnessSpinner.Limits = [0 Inf];
            app.RelativeBrightnessSpinner.ValueChangedFcn = createCallbackFcn(app, @RelativeBrightnessSpinnerValueChanged, true);
            app.Label_3 = uilabel(app.TuningAreaButtonGroup);
            app.Label_3.Text = 'Contrast';
            app.ContrastSpinner = uispinner(app.TuningAreaButtonGroup);
            app.ContrastSpinner.ValueChangingFcn = createCallbackFcn(app, @ContrastSpinnerValueChanging, true);
            app.Label_2 = uilabel(app.TuningAreaButtonGroup);
            app.Label_2.Text = 'Ambiguity';
            app.AmbiguitySlider = uislider(app.TuningAreaButtonGroup);
            app.AmbiguitySlider.ValueChangingFcn = createCallbackFcn(app, @AmbiguitySliderValueChanging, true);
            app.SpecularTransformationKnobLabel = uilabel(app.TuningAreaButtonGroup);
            app.SpecularTransformationKnobLabel.Text = 'Specular Transformation';
            app.SpecularTransformationKnob = uiknob(app.TuningAreaButtonGroup, 'discrete');
            app.SpecularTransformationKnob.Items = {'Off', 'X-axis', 'Y-axis'};
            app.SpecularTransformationKnob.ValueChangedFcn = createCallbackFcn(app, @SpecularTransformationKnobValueChanged, true);
            app.ImageSegmentationButtonGroup = uibuttongroup(app.ImageSegmentationUIFigure);
            app.ImageSegmentationButtonGroup.Title = 'Image Segmentation';
            app.WatershedSegmentationButton = uibutton(app.ImageSegmentationButtonGroup, 'push');
            app.WatershedSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @WatershedSegmentationButtonPushed, true);
            app.WatershedSegmentationButton.Text = 'Watershed Segmentation';
            app.RegionalGrowthButton = uibutton(app.ImageSegmentationButtonGroup, 'push');
            app.RegionalGrowthButton.ButtonPushedFcn = createCallbackFcn(app, @RegionalGrowthButtonPushed, true);
            app.RegionalGrowthButton.Text = 'Regional Growth';
            app.HoughTransformCircleButton = uibutton(app.ImageSegmentationButtonGroup, 'push');
            app.HoughTransformCircleButton.ButtonPushedFcn = createCallbackFcn(app, @HoughTransformCircleButtonPushed, true);
            app.HoughTransformCircleButton.Text = 'Hough Transform—Circle';
            app.EdgeDetectionButtonGroup = uibuttongroup(app.ImageSegmentationButtonGroup);
            app.EdgeDetectionButtonGroup.Title = 'Edge Detection';
            app.SobelButton = uibutton(app.EdgeDetectionButtonGroup, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.RobertsButton = uibutton(app.EdgeDetectionButtonGroup, 'push');
            app.RobertsButton.ButtonPushedFcn = createCallbackFcn(app, @RobertsButtonPushed, true);
            app.RobertsButton.Text = 'Roberts';
            app.LOGButton = uibutton(app.EdgeDetectionButtonGroup, 'push');
            app.LOGButton.ButtonPushedFcn = createCallbackFcn(app, @LOGButtonPushed, true);
            app.LOGButton.Text = 'LOG';
            app.PrewittButton = uibutton(app.EdgeDetectionButtonGroup, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.CannyButton = uibutton(app.EdgeDetectionButtonGroup, 'push');
            app.CannyButton.ButtonPushedFcn = createCallbackFcn(app, @CannyButtonPushed, true);
            app.CannyButton.Text = 'Canny';
            app.HoughTransformLineButton = uibutton(app.ImageSegmentationButtonGroup, 'push');
            app.HoughTransformLineButton.ButtonPushedFcn = createCallbackFcn(app, @HoughTransformLineButtonPushed, true);
            app.HoughTransformLineButton.Text = 'Hough Transform—Line';
            app.ThresholdSegementSpinnerLabel = uilabel(app.ImageSegmentationButtonGroup);
            app.ThresholdSegementSpinnerLabel.Text = 'Threshold Segement';
            app.ThresholdSegementSpinner = uispinner(app.ImageSegmentationButtonGroup);
            app.ThresholdSegementSpinner.ValueChangedFcn = createCallbackFcn(app, @ThresholdSegementSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.ImageSegmentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lzl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageSegmentationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageSegmentationUIFigure)
        end
    end
end