classdef ry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MorphologicalProcessingUIFigure  matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        PushTool2                      matlab.ui.container.toolbar.PushTool
        PushTool3                      matlab.ui.container.toolbar.PushTool
        PushTool4                      matlab.ui.container.toolbar.PushTool
        RegionFillingParametersPanel   matlab.ui.container.Panel
        FillingHoleButtonGroup         matlab.ui.container.ButtonGroup
        holesButton                    matlab.ui.control.RadioButton
        nothingButton                  matlab.ui.control.RadioButton
        TwodimensionalPixelConnectivityButtonGroup  matlab.ui.container.ButtonGroup
        Button_2                       matlab.ui.control.RadioButton
        Button                         matlab.ui.control.RadioButton
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        LocationsLabel                 matlab.ui.control.Label
        ConnectedExtractParametersPanel  matlab.ui.container.Panel
        BinaryThresholdSlider          matlab.ui.control.Slider
        BinaryThresholdSliderLabel     matlab.ui.control.Label
        SizeofStructureElementSpinner  matlab.ui.control.Spinner
        SizeofStructureElementLabel    matlab.ui.control.Label
        NumbersofIterationsSpinner     matlab.ui.control.Spinner
        NumbersofIterationsSpinnerLabel  matlab.ui.control.Label
        EdgeDetectionOperatorsButtonGroup  matlab.ui.container.ButtonGroup
        CannyButton                    matlab.ui.control.ToggleButton
        LogButton_2                    matlab.ui.control.ToggleButton
        ApproxcannyButton_2            matlab.ui.control.ToggleButton
        RobertsButton                  matlab.ui.control.ToggleButton
        PrewittButton                  matlab.ui.control.ToggleButton
        SobelButton                    matlab.ui.control.ToggleButton
        OperationPanel                 matlab.ui.container.Panel
        BoundaryExtractButton          matlab.ui.control.Button
        RegionFillingButton            matlab.ui.control.Button
        ConnectionExtractButton        matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        ReconstructionButton           matlab.ui.control.Button
        ErodeButton                    matlab.ui.control.Button
        DilateButton                   matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        MorpologicalStructureElementPanel  matlab.ui.container.Panel
        okButton                       matlab.ui.control.Button
        degEditField                   matlab.ui.control.NumericEditField
        degEditFieldLabel              matlab.ui.control.Label
        lenEditField                   matlab.ui.control.NumericEditField
        lenEditFieldLabel              matlab.ui.control.Label
        nEditField                     matlab.ui.control.NumericEditField
        nEditFieldLabel                matlab.ui.control.Label
        mEditField                     matlab.ui.control.NumericEditField
        mEditFieldLabel                matlab.ui.control.Label
        wEditField                     matlab.ui.control.NumericEditField
        wEditFieldLabel                matlab.ui.control.Label
        rEditField                     matlab.ui.control.NumericEditField
        rEditFieldLabel                matlab.ui.control.Label
        GeometricneighborhoodDropDown  matlab.ui.control.DropDown
        GeometricneighborhoodDropDownLabel  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
    end
    
    properties (Access = private)
        Property2 % Description
        I;
        IOpen;
        IClose;
        IErode;
        IDilate;
        Operator;
        BW;
         ICE;
        Pic;
        Threshold;
        upperLimit;
        st;
        r;
        w;
        m;
        n;
        deg;
        len;
        SE;
        OutputFilename;
        conn;
        h;
        x;
        y;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: MorpologicalStructureElementPanel
        function MorpologicalStructureElementPanelSizeChanged(app, event)
       
        end

        % Button down function: MorpologicalStructureElementPanel
        function MorpologicalStructureElementPanelButtonDown(app, event)
            
        end

        % Value changed function: rEditField
        function rEditFieldValueChanged(app, event)
            value = app.rEditField.Value;
            app.r=value;
        end

        % Value changed function: wEditField
        function wEditFieldValueChanged(app, event)
            value = app.wEditField.Value;
            app.w=value;
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            app.m=value;
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            app.n=value;
        end

        % Value changed function: lenEditField
        function lenEditFieldValueChanged(app, event)
            value = app.lenEditField.Value;
            app.len=value;
        end

        % Value changed function: degEditField
        function degEditFieldValueChanged(app, event)
            value = app.degEditField.Value;
            app.deg=value;
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
          switch app.st
                case 'diamond'
                    se = strel("diamond",app.r);
                case 'disk'
                    se = strel("disk",app.r);
                case 'octagon'
                    se = strel("octagon",app.r);
                case 'line'
                    se = strel("line",app.len,app.deg);
                case 'rectangle'
                    se = strel("rectangle",[app.m app.n]);
                case 'square'
                    se = strel("square",app.w);
          end
          app.SE=se;
        end

        % Selection changed function: EdgeDetectionOperatorsButtonGroup
        function EdgeDetectionOperatorsButtonGroupSelectionChanged(app, event)
            selectedButton = app.EdgeDetectionOperatorsButtonGroup.SelectedObject;
            switch selectedButton
                case app.SobelButton
                    app.Operator='Sobel';
                case app.PrewittButton
                    app.Operator='Prewitt';
                case app.RobertsButton
                    app.Operator='Roberts';
                case app.LogButton_2
                    app.Operator='Log';
                case app.CannyButton
                    app.Operator='Canny';
                case app.ApproxcannyButton_2
                    app.Operator='Approxcanny';
            end
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
           app.IOpen=imopen(app.I,app.SE);
            imshow(app.IOpen,'Parent', app.UIAxes2);
            app.Pic=app.IOpen;
            
        end

        % Button pushed function: BoundaryExtractButton
        function BoundaryExtractButtonPushed(app, event)
            switch app.Operator
                case 'Sobel'
                    app.BW=edge(app.I,"sobel");
                case 'Prewitt'
                    app.BW=edge(app.I,'prewitt');
                case 'Roberts'
                    app.BW=edge(app.I, 'roberts');
                case 'Log'
                    app.BW=edge(app.I, 'log');
                case 'Zerocross'
                    app.BW=edge(app.I, 'zerocross');
                case 'Canny'
                    app.BW=edge(app.I, 'canny');
                case 'Approxcanny'
                    app.BW=edge(app.I, 'approxcanny');
            end
            imshow(app.BW,'Parent', app.UIAxes2)
            app.Pic=app.BW;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            switch app.st
                case 'diamond'
                    app.SE = strel("diamond",app.r);
                case 'disk'
                    app.SE = strel("disk",app.r);
                case 'octagon'
                    app.SE = strel("octagon",app.r);
                case 'line'
                    app.SE = strel("line",app.len,app.deg);
                case 'rectangle'
                    app.SE = strel("rectangle",[app.m app.n]);
                case 'square'
                    app.SE = strel("square",app.w);
            end
            app.IClose=imclose(app.I,app.SE);
            imshow(app.IClose,'Parent', app.UIAxes2)
            app.Pic=app.IClose;
        end

        % Value changed function: NumbersofIterationsSpinner
        function NumbersofIterationsSpinnerValueChanged(app, event)
            value = app.NumbersofIterationsSpinner.Value;
            app.upperLimit=value;
        end

        % Value changed function: SizeofStructureElementSpinner
        function SizeofStructureElementSpinnerValueChanged(app, event)
            value = app.SizeofStructureElementSpinner.Value;
            app.n=value;
        end

        % Value changed function: BinaryThresholdSlider
        function BinaryThresholdSliderValueChanged(app, event)
            value = app.BinaryThresholdSlider.Value;
            app.Threshold=value;
        end

        % Button pushed function: ConnectionExtractButton
        function ConnectionExtractButtonPushed(app, event)
            function R=FindConnectedPoint(p,Beta_pad,ind,n_l)
                %保存边界坐标对象
                Obj=[];
                Block=Beta_pad(p(1,1))-n_l:p(1,1)+n_l; p(1,2)-n_l:p(1,2)+n_l;
                Obj=p;
                Beta_pad(p(1,1),p(1,2))=0;
                Block(n_l+1,n_l+1)=0;
                A=cell(1,3);
                %将p点从下标矩阵中删除
                for j=1:size(ind,1)
                    if ind(j,:)==p
                        ind(j,:)=[];
                        break;
                    end
                end
               % 寻找块区域中为1的点的下标
                [ind_rows, ind_cols] = find(Block==1);
                ind_sub = [ind_rows, ind_cols];
                if ~isempty(ind_sub)
                    % 确定块中数值为1的点的坐标
                    for i = 1:size(ind_sub,1) 
                        p_next = p;
                        % 计算下一个像素点的坐标
                        if ind_sub(i,1) <= n_l+1
                            p_next(1) = p(1) - abs(n_l+1-ind_sub(i,1));
                            if ind_sub(i,2) <= n_l+1                    
                                p_next(2) = p(2) - abs(n_l+1-ind_sub(i,2));
                            else
                                p_next(2) = p(2) + abs(n_l+1-ind_sub(i,2));                    
                            end
                        else
                            p_next(1) = p(1) + abs(n_l+1-ind_sub(i,1));
                            if ind_sub(i,2) <= n_l+1                    
                                p_next(2) = p(2) - abs(n_l+1-ind_sub(i,2));
                            else
                                p_next(2) = p(2) + abs(n_l+1-ind_sub(i,2));                    
                            end                
                        end
                        % 判断是否已经处理过该像素点
                        if Beta_pad(p_next(1),p_next(2)) ~= 0 
                            A = FindConnectedPoint(p_next,Beta_pad,ind,n_l);
                            % 将边界线对象保存至对象集
                            Obj = [A{1}, Obj];
                            % 更新下标矩阵
                            ind = A{2};
                            Beta_pad(p_next(1),p_next(2)) = 0;
                        end                       
                    end
                end
                %返回结果
                R={Obj,ind,Beta_pad};
             end
            
            II=im2double(app.I);
            [x,y]=size(II); % picture size
            th=app.Threshold; %threshold to Binary Processing
            ind=find(II>=th);
            II(ind)=1;
            ind=find(II<th);
            II(ind)=0;
            [rows,cols]=find(II==1); % 获得值为1的坐标
            index=cat(2,rows,cols);  % cat串联数组
            % 结构元素
            st=app.n;
            B=ones(st,st);
            n_l=floor(st/2);
            % 对边界图进行扩充
            I_pad=padarray(II,[n_l,n_l]);
            % 下标矩阵＋2
            index=index+2;
            Objs={}; % 保存所有连通对象集
            set(0,'RecursionLimit',app.upperLimit)
            % 递归寻找连通对象
            while 1
                if isempty(index)
                    break;
                end
                p=index(1,:);
                A=FindConnectedPoint(p,I_pad,index,n_l);
                %将边界线对象保存至对象集
                Objs=cat(1,Objs,A{1,1}-n_l);
                index=A{1,2};
                I_pad=A{1,3};
            end
            %显示连通对象
            J=zeros(x,y);
            if ~isempty(Objs)
                for i=1:size(Objs,1)
                    Obj=Objs{i,1};
                    for j=1:size(Obj,1)
                        J(Obj(j,1),Obj(j,2))=1;
                    end
                end
            end
            app.ICE=J;
            imshow(app.ICE,'Parent',app.UIAxes2)
            app.Pic=app.ICE;
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
             switch app.st
                case 'diamond'
                    app.SE = strel("diamond",app.r);
                case 'disk'
                    app.SE = strel("disk",app.r);
                case 'octagon'
                    app.SE = strel("octagon",app.r);
                case 'line'
                    app.SE = strel("line",app.len,app.deg);
                case 'rectangle'
                    app.SE = strel("rectangle",[app.m app.n]);
                case 'square'
                    app.SE = strel("square",app.w);
             end
            app.IDilate=imdilate(app.I,app.SE);
            imshow(app.IDilate,'Parent', app.UIAxes2)
            app.Pic=app.IDilate;
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            switch app.st
                case 'diamond'
                    app.SE = strel("diamond",app.r);
                case 'disk'
                    app.SE = strel("disk",app.r);
                case 'octagon'
                    app.SE = strel("octagon",app.r);
                case 'line'
                    app.SE = strel("line",app.len,app.deg);
                case 'rectangle'
                    app.SE = strel("rectangle",[app.m app.n]);
                case 'square'
                    app.SE = strel("square",app.w);
            end
            app.IErode=imerode(app.I,app.SE);
            imshow(app.IErode,'Parent', app.UIAxes2)
            app.Pic=app.IErode;
        end

        % Button pushed function: ReconstructionButton
        function ReconstructionButtonPushed(app, event)
             mask = adapthisteq(app.I);  %调整图像的对比度以创建蒙版图像
            marker = imerode(mask,app.SE);  %创建一个标记图像，该图像使用形态侵蚀识别图像中的高强度对象
            obr = imreconstruct(marker,mask);
            imshow(obr,'Parent',app.UIAxes2);
            app.Pic=obr;
        end

        % Selection changed function: 
        % TwodimensionalPixelConnectivityButtonGroup
        function TwodimensionalPixelConnectivityButtonGroupSelectionChanged(app, event)
            selectedButton = app.TwodimensionalPixelConnectivityButtonGroup.SelectedObject.Text;
           if strcmp(selectedButton, '4')
               app.conn=4;
           else
               app.conn=8;
           end
        end

        % Selection changed function: FillingHoleButtonGroup
        function FillingHoleButtonGroupSelectionChanged(app, event)
            selectedButton = app.FillingHoleButtonGroup.SelectedObject.Text;
        if strcmp(selectedButton, 'holes')
            app.h = 'holes';
            app.LocationsLabel.Visible = 'off';
            app.xEditField.Visible = 'off';
            app.yEditField.Visible = 'off';
            app.TwodimensionalPixelConnectivityButtonGroup.Visible="off";
        else
            app.LocationsLabel.Visible = 'on';
            app.xEditField.Visible = 'on';
            app.yEditField.Visible = 'on';
            app.TwodimensionalPixelConnectivityButtonGroup.Visible="on";
        end
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.x=value;
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            app.y=value;
        end

        % Button pushed function: RegionFillingButton
        function RegionFillingButtonPushed(app, event)
            Loc = [app.x app.y];
            if strcmp(app.h, "holes")
                Imd=imdilate(app.I, app.SE);
                Imde=imerode(Imd, app.SE);
                app.BW = imfill(Imde, "holes");
            else
                app.BW = imfill(app.I, Loc, app.conn);
            end
            imshow(app.BW, 'Parent', app.UIAxes2)
            app.Pic = app.BW;
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
             [filename,pathname] = uigetfile({'*.jpg';'*.bmp';'*.tif';'*.png';'*.*'},'Choose an Image');
            if isequal(filename,0)||isequal(pathname,0)
                msgbox('You did not select an Image!','Error');
                return;
            else
                file=strcat(pathname,filename);
                app.I=imread(file);
                imshow(app.I,'Parent',app.UIAxes)
            end
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            [filename,pathname]=uiputfile({'*.jpg';'*.bmp';'*.tif';'*,png'},'Save Image','untitled.png');
            if ~isequal([filename,pathname],[0,0])
                Mypic=fullfile(path,filename);
                imwrite(app.Pic,Mypic);
                msgbox('Save Successfully');
            else
                msgbox('Fail to Save');
            end
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            choice=questdlg('Do you want to exit? ','Exit','Yes','No','No');
            switch choice
                case 'Yes'
                    delete(app.MorphologicalProcessingUIFigure);
                    return;
                case 'No'
                    return;
            end
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Value changed function: GeometricneighborhoodDropDown
        function GeometricneighborhoodDropDownValueChanged(app, event)
            value = app.GeometricneighborhoodDropDown.Value;
            switch value
                case 'diamond'
                    app.st='diamond';
                    app.rEditField.Visible='on';
                    app.wEditField.Visible='off';
                    app.mEditField.Visible='off';
                    app.nEditField.Visible='off';
                    app.lenEditField.Visible='off';
                    app.degEditField.Visible='off';
                case 'disk'
                    app.st='disk';
                    app.rEditField.Visible='on';
                    app.wEditField.Visible='off';
                    app.mEditField.Visible='off';
                    app.nEditField.Visible='off';
                    app.lenEditField.Visible='off';
                    app.degEditField.Visible='off';
                case 'octagon'
                    app.st='octagon';
                    app.rEditField.Visible='on';
                    app.wEditField.Visible='off';
                    app.mEditField.Visible='off';
                    app.nEditField.Visible='off';
                    app.lenEditField.Visible='off';
                    app.degEditField.Visible='off';
                case 'line'
                    app.st='line';
                    app.rEditField.Visible='off';
                    app.wEditField.Visible='off';
                    app.mEditField.Visible='off';
                    app.nEditField.Visible='off';
                    app.lenEditField.Visible='on';
                    app.degEditField.Visible='on';
                case 'rectangle'
                    app.st='rectangle';
                    app.rEditField.Visible='off';
                    app.wEditField.Visible='off';
                    app.mEditField.Visible='on';
                    app.nEditField.Visible='on';
                    app.lenEditField.Visible='off';
                    app.degEditField.Visible='off';
                case 'square'
                    app.st='square';
                    app.rEditField.Visible='off';
                    app.wEditField.Visible='on';
                    app.mEditField.Visible='off';
                    app.nEditField.Visible='off';
                    app.lenEditField.Visible='off';
                    app.degEditField.Visible='off';
            end
        end

        % Drop down opening function: GeometricneighborhoodDropDown
        function GeometricneighborhoodDropDownOpening(app, event)
            
        end

        % Callback function
        function GeometricneighborhoodDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Clicked callback: PushTool4
        function PushTool4Clicked(app, event)
            run Main_new.mlapp
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MorphologicalProcessingUIFigure = uifigure('Visible', 'off');
            app.MorphologicalProcessingUIFigure.Name = 'Morphological Processing';
            app.MorphologicalProcessingUIFigure.Icon = fullfile(pathToMLAPP, 'ry标识符.png');
            app.Toolbar = uitoolbar(app.MorphologicalProcessingUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Import an Image'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, '导入.jpg');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Save the Processed Image'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, '保存.jpg');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Exit'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, '退出.jpg');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'Return to upper level'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, '功能界面图标.png');
            app.UIAxes = uiaxes(app.MorphologicalProcessingUIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.MorphologicalProcessingUIFigure);
            title(app.UIAxes2, 'Processed Image')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.MorpologicalStructureElementPanel = uipanel(app.MorphologicalProcessingUIFigure);
            app.MorpologicalStructureElementPanel.Title = 'Morpological Structure Element';
            app.MorpologicalStructureElementPanel.SizeChangedFcn = createCallbackFcn(app, @MorpologicalStructureElementPanelSizeChanged, true);
            app.MorpologicalStructureElementPanel.ButtonDownFcn = createCallbackFcn(app, @MorpologicalStructureElementPanelButtonDown, true);
            app.GeometricneighborhoodDropDownLabel = uilabel(app.MorpologicalStructureElementPanel);
            app.GeometricneighborhoodDropDownLabel.Text = {'Geometric '; 'neighborhood'};
            app.GeometricneighborhoodDropDown = uidropdown(app.MorpologicalStructureElementPanel);
            app.GeometricneighborhoodDropDown.Items = {'diamond', 'line', 'disk', 'octagon', 'rectangle', 'square'};
            app.GeometricneighborhoodDropDown.DropDownOpeningFcn = createCallbackFcn(app, @GeometricneighborhoodDropDownOpening, true);
            app.GeometricneighborhoodDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometricneighborhoodDropDownValueChanged, true);
            app.GeometricneighborhoodDropDown.Tooltip = {''};
            app.GeometricneighborhoodDropDown.Value = 'diamond';
            app.rEditFieldLabel = uilabel(app.MorpologicalStructureElementPanel);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.MorpologicalStructureElementPanel, 'numeric');
            app.rEditField.ValueChangedFcn = createCallbackFcn(app, @rEditFieldValueChanged, true);
            app.rEditField.Tooltip = {'For octagonal structuring elements, the SIZE input must be a non-negative multiple of 3.'};
            app.wEditFieldLabel = uilabel(app.MorpologicalStructureElementPanel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.MorpologicalStructureElementPanel, 'numeric');
            app.wEditField.ValueChangedFcn = createCallbackFcn(app, @wEditFieldValueChanged, true);
            app.mEditFieldLabel = uilabel(app.MorpologicalStructureElementPanel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.MorpologicalStructureElementPanel, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.nEditFieldLabel = uilabel(app.MorpologicalStructureElementPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.MorpologicalStructureElementPanel, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.lenEditFieldLabel = uilabel(app.MorpologicalStructureElementPanel);
            app.lenEditFieldLabel.Text = 'len';
            app.lenEditField = uieditfield(app.MorpologicalStructureElementPanel, 'numeric');
            app.lenEditField.ValueChangedFcn = createCallbackFcn(app, @lenEditFieldValueChanged, true);
            app.degEditFieldLabel = uilabel(app.MorpologicalStructureElementPanel);
            app.degEditFieldLabel.Text = 'deg';
            app.degEditField = uieditfield(app.MorpologicalStructureElementPanel, 'numeric');
            app.degEditField.ValueChangedFcn = createCallbackFcn(app, @degEditFieldValueChanged, true);
            app.okButton = uibutton(app.MorpologicalStructureElementPanel, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            app.OperationPanel = uipanel(app.MorphologicalProcessingUIFigure);
            app.OperationPanel.Title = ' Operation';
            app.OpenButton = uibutton(app.OperationPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Morpological Structure Element'};
            app.OpenButton.Text = 'Open';
            app.DilateButton = uibutton(app.OperationPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Morpological Structure Element'};
            app.DilateButton.Text = 'Dilate';
            app.ErodeButton = uibutton(app.OperationPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Morpological Structure Element'};
            app.ErodeButton.Text = 'Erode';
            app.ReconstructionButton = uibutton(app.OperationPanel, 'push');
            app.ReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionButtonPushed, true);
            app.ReconstructionButton.Tooltip = {'Morpological Structure Element'};
            app.ReconstructionButton.Text = 'Reconstruction';
            app.CloseButton = uibutton(app.OperationPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Morpological Structure Element'};
            app.CloseButton.Text = 'Close';
            app.ConnectionExtractButton = uibutton(app.OperationPanel, 'push');
            app.ConnectionExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectionExtractButtonPushed, true);
            app.ConnectionExtractButton.Tooltip = {'Connected Extract Parameters'};
            app.ConnectionExtractButton.Text = 'Connection Extract';
            app.RegionFillingButton = uibutton(app.OperationPanel, 'push');
            app.RegionFillingButton.ButtonPushedFcn = createCallbackFcn(app, @RegionFillingButtonPushed, true);
            app.RegionFillingButton.Tooltip = {'"holes":Region Filling Parameters + Morphological Structure Element'; '"nothing": Region Filling Parameters'};
            app.RegionFillingButton.Text = 'Region Filling';
            app.BoundaryExtractButton = uibutton(app.OperationPanel, 'push');
            app.BoundaryExtractButton.ButtonPushedFcn = createCallbackFcn(app, @BoundaryExtractButtonPushed, true);
            app.BoundaryExtractButton.Tooltip = {'Edge Detection Operators'};
            app.BoundaryExtractButton.Text = 'Boundary Extract';
            app.EdgeDetectionOperatorsButtonGroup = uibuttongroup(app.MorphologicalProcessingUIFigure);
            app.EdgeDetectionOperatorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EdgeDetectionOperatorsButtonGroupSelectionChanged, true);
            app.EdgeDetectionOperatorsButtonGroup.Title = 'Edge Detection Operators';
            app.SobelButton = uitogglebutton(app.EdgeDetectionOperatorsButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.SobelButton.Value = true;
            app.PrewittButton = uitogglebutton(app.EdgeDetectionOperatorsButtonGroup);
            app.PrewittButton.Text = 'Prewitt';
            app.RobertsButton = uitogglebutton(app.EdgeDetectionOperatorsButtonGroup);
            app.RobertsButton.Text = 'Roberts';
            app.ApproxcannyButton_2 = uitogglebutton(app.EdgeDetectionOperatorsButtonGroup);
            app.ApproxcannyButton_2.Text = 'Approxcanny';
            app.LogButton_2 = uitogglebutton(app.EdgeDetectionOperatorsButtonGroup);
            app.LogButton_2.Text = 'Log';
            app.CannyButton = uitogglebutton(app.EdgeDetectionOperatorsButtonGroup);
            app.CannyButton.Text = 'Canny';
            app.ConnectedExtractParametersPanel = uipanel(app.MorphologicalProcessingUIFigure);
            app.ConnectedExtractParametersPanel.Title = 'Connected Extract Parameters';
            app.NumbersofIterationsSpinnerLabel = uilabel(app.ConnectedExtractParametersPanel);
            app.NumbersofIterationsSpinnerLabel.Text = {'Numbers of'; 'Iterations'};
            app.NumbersofIterationsSpinner = uispinner(app.ConnectedExtractParametersPanel);
            app.NumbersofIterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumbersofIterationsSpinnerValueChanged, true);
            app.SizeofStructureElementLabel = uilabel(app.ConnectedExtractParametersPanel);
            app.SizeofStructureElementLabel.Text = {'Size of '; 'Structure Element'};
            app.SizeofStructureElementSpinner = uispinner(app.ConnectedExtractParametersPanel);
            app.SizeofStructureElementSpinner.ValueChangedFcn = createCallbackFcn(app, @SizeofStructureElementSpinnerValueChanged, true);
            app.BinaryThresholdSliderLabel = uilabel(app.ConnectedExtractParametersPanel);
            app.BinaryThresholdSliderLabel.Text = {'Binary '; 'Threshold'};
            app.BinaryThresholdSlider = uislider(app.ConnectedExtractParametersPanel);
            app.BinaryThresholdSlider.Limits = [0 1];
            app.BinaryThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinaryThresholdSliderValueChanged, true);
            app.RegionFillingParametersPanel = uipanel(app.MorphologicalProcessingUIFigure);
            app.RegionFillingParametersPanel.Title = 'Region Filling Parameters';
            app.LocationsLabel = uilabel(app.RegionFillingParametersPanel);
            app.LocationsLabel.Text = 'Locations';
            app.xEditFieldLabel = uilabel(app.RegionFillingParametersPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.RegionFillingParametersPanel, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.RegionFillingParametersPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.RegionFillingParametersPanel, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.TwodimensionalPixelConnectivityButtonGroup = uibuttongroup(app.RegionFillingParametersPanel);
            app.TwodimensionalPixelConnectivityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TwodimensionalPixelConnectivityButtonGroupSelectionChanged, true);
            app.TwodimensionalPixelConnectivityButtonGroup.Title = 'Two-dimensional Pixel Connectivity';
            app.Button = uiradiobutton(app.TwodimensionalPixelConnectivityButtonGroup);
            app.Button.Text = '4';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.TwodimensionalPixelConnectivityButtonGroup);
            app.Button_2.Text = '8';
            app.FillingHoleButtonGroup = uibuttongroup(app.RegionFillingParametersPanel);
            app.FillingHoleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FillingHoleButtonGroupSelectionChanged, true);
            app.FillingHoleButtonGroup.Title = 'Filling Hole';
            app.nothingButton = uiradiobutton(app.FillingHoleButtonGroup);
            app.nothingButton.Text = 'nothing';
            app.nothingButton.Value = true;
            app.holesButton = uiradiobutton(app.FillingHoleButtonGroup);
            app.holesButton.Text = 'holes';
            % Show the figure after all components are created
            app.MorphologicalProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MorphologicalProcessingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MorphologicalProcessingUIFigure)
        end
    end
end