classdef wxy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool2                       matlab.ui.container.toolbar.PushTool
        PushTool4                       matlab.ui.container.toolbar.PushTool
        PushTool5                       matlab.ui.container.toolbar.PushTool
        HistogramLabel                  matlab.ui.control.Label
        ImageEnhancementButtonGroup     matlab.ui.container.ButtonGroup
        GammaSpinner                    matlab.ui.control.Spinner
        GammaSpinnerLabel               matlab.ui.control.Label
        HistogramequalizationButton     matlab.ui.control.Button
        GrayscaletransformationListBox  matlab.ui.control.ListBox
        Label                           matlab.ui.control.Label
        uibuttongroup4                  matlab.ui.container.ButtonGroup
        NegativeBinarizationButton      matlab.ui.control.Button
        UpsidedownButton                matlab.ui.control.Button
        RotationSlider                  matlab.ui.control.Slider
        RotationSliderLabel             matlab.ui.control.Label
        BinarizationButton              matlab.ui.control.Button
        GrayButton                      matlab.ui.control.Button
        ClipButton                      matlab.ui.control.Button
        m8                              matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        originalpicture % Description
        changedpicture
             
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            % 加载图像
            [filename,pathname] = uigetfile({'*.jpg';'*.bmp';'*.tif';'*.*'},"Choose an image");%此处的代码是通过访问图像，返回图像的文件名和路径
            
            % 判断是否成功选择文件，如果没有则提示错误提示
            if isequal(filename,0) || isequal(pathname,0)          
                errordlg('You did not select an Image!','Error');   % 错误提示的信息
            else
                % 将文件名和路径合成，可以访问图片
                file_name = [pathname,filename];           
                % 读取图片
                app.originalpicture = imread(file_name);   
                
                % 显示原图在app.UIAxes
                imshow(app.originalpicture,'Parent',app.UIAxes);   
                
                % 显示原图直方图
                origin_histo = imhist(app.originalpicture);
                plot(app.UIAxes3,origin_histo)
            end

            app.changedpicture = app.originalpicture;
        end

        % Button pushed function: ClipButton
        function ClipButtonPushed(app, event)
            % 裁剪图像
            % 判断待处理图片是否存在，如果不存在提示错误
            if isequal(app.changedpicture,'')
                msgbox('No pictures','Warning',"help");
            else
                clip = imcrop(app.changedpicture);
                % 将裁剪后的图像显示在坐标轴中
                imshow(clip,'Parent',app.UIAxes4);

                % 将处理后的图片存为app.changedpicture，以便后续对裁剪后的图像进行进一步操作
                app.changedpicture = clip;
            end
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            % 保存图像
            [filename,pathname] = uiputfile({'*.jpg';'*.bmp';'*.tif';'*,png'},'Save Image','untitled.tif');
            if ~isequal([filename,pathname],[0,0])
                % 将文件名和路径合成，可以访问图片
                file_name = strcat(pathname,filename);
                imwrite(app.changedpicture,file_name);
                % 显示保存成功
                msgbox('Save successfully');
            else
                % 显示保存失败
                msgbox('Fail to save');
            end
        end

        % Clicked callback: PushTool4
        function PushTool4Clicked(app, event)
            % 退出
            choice=questdlg('Do you want to exit? ','Exit','Yes','No','No');
            switch choice
                case 'Yes'
                    delete(app);
                    return;
                case 'No'
                    return;
            end
        end

        % Value changed function: RotationSlider
        function RotationSliderValueChanged(app, event)
            % 旋转图像
            % 判断待处理图片是否存在，如果不存在提示警告
            if isequal(app.changedpicture,'')
                msgbox('No pictures','Warning',"help");
            else
                value = app.RotationSlider.Value;       % 滑块组件的值
                rotatedImage = imrotate(app.changedpicture, value);
                % 在坐标轴中显示旋转后的图像
                imshow(rotatedImage, 'Parent', app.UIAxes4); 
                app.changedpicture = rotatedImage; 
            end
        end

        % Button pushed function: BinarizationButton
        function BinarizationButtonPushed(app, event)
            % 图像二值化
            % 判断待处理图片是否存在，如果不存在提示警告
            if isequal(app.changedpicture,'')
                msgbox('No pictures','Warning',"help");
            else
                binaryimg = im2bw(app.changedpicture);     % 二值化
                % 在坐标轴中显示二值化后的图像
                imshow(binaryimg,'Parent',app.UIAxes4);
                app.changedpicture = binaryimg; 
            end
        end

        % Button pushed function: GrayButton
        function GrayButtonPushed(app, event)
            % 图像灰度化
            % 判断待处理图片是否存在，如果不存在提示警告
            if isequal(app.originalpicture,'')
                msgbox('No pictures','Warning',"help");
            else
                grayimg = im2gray(app.originalpicture);   % 灰度化
                imshow(grayimg,'Parent',app.UIAxes4);
                app.changedpicture = grayimg;
            end
        end

        % Value changed function: GrayscaletransformationListBox
        function GrayscaletransformationListBoxValueChanged(app, event)
            % 图像增强处理中的对数变换、线性反比变换
            % 提示：每一步操作是在上一步操作得到的图像基础上进行的，故想对原图进行相关操作请重新加载原图
            value = app.GrayscaletransformationListBox.Value;
            % 判断待处理图片是否存在，如果不存在提示警告
            if isequal(app.changedpicture,'')
                msgbox('No pictures','Warning',"help");
            else
                switch value
                    % 对数变换
                    case 'Logarithmic'   
                        % 对数运算设计小数，需将图片转为double ，相当于图片像素值除以255
                        doubleimg = double(app.changedpicture);
                        logimg = log(1+doubleimg);
                        logimg = mat2gray(logimg);          % 将图像转为灰度图
                        logimg = im2uint8(logimg);          % 将图像转换为 8 位无符号整数
                        imshow(logimg,'Parent',app.UIAxes4);
                        % 显示变换后图像的直方图
                        change_histo = imhist(logimg);
                        plot(app.UIAxes2,change_histo)
                        app.changedpicture = logimg;

                    % 线性反比变换
                    case 'Linear-Negative'   
                        grayimg = im2gray(app.changedpicture);
                        im = 255-grayimg;
                        imshow(im,'Parent',app.UIAxes4)
                        % 显示变换后图像的直方图
                        change_histo = imhist(im);
                        plot(app.UIAxes2,change_histo)
                        app.changedpicture = im;

                    otherwise
                        msgbox('Opeeration Error','Warning',"help"); 
                end
            end
        end

        % Button pushed function: HistogramequalizationButton
        function HistogramequalizationButtonPushed(app, event)
            % 直方图均衡
            % 判断待处理图片是否存在，如果不存在提示警告
            if isequal(app.changedpicture,'')
                msgbox('No pictures','Warning',"help");
            else
                hisqua = histeq(app.changedpicture);   % 函数histeq()进行直方图均衡化处理，括号中第二个参数为均衡化后的灰度级数，默认值为64
                imshow(hisqua,'Parent',app.UIAxes4)
                % 显示变换后图像的直方图
                change_histo = imhist(hisqua);
                plot(app.UIAxes2,change_histo)
                app.changedpicture = hisqua;
            end
        end

        % Button pushed function: UpsidedownButton
        function UpsidedownButtonPushed(app, event)
            % 上下对调
            if isequal(app.changedpicture,'')
                msgbox('No pictures','Warning',"help");
            else
                updown = flipud(app.changedpicture);  % 上下对调图片
                imshow(updown,'Parent',app.UIAxes4);  % 将上下对调后的图片显示在坐标轴2中
            end
            app.changedpicture = updown;
        end

        % Button pushed function: m8
        function m8ButtonPushed(app, event)
            % 左右对调
            if isequal(app.changedpicture,'')
                msgbox('No pictures','Warning',"help");
            else
                leftright =fliplr(app.changedpicture);   % 左右对调图片
                imshow(leftright,'Parent',app.UIAxes4);  % 将左右对调后的图片显示在坐标轴2中
            end
            app.changedpicture = leftright;
        end

        % Value changed function: GammaSpinner
        function GammaSpinnerValueChanged(app, event)
            % 伽马变换
            value = app.GammaSpinner.Value;   % 微调器中的值
            
            if isequal(app.changedpicture,'')
                msgbox('No pictures','Warning',"help");
            else
                im = im2double(app.changedpicture);
                im = im.^value;
                imshow(im,'Parent',app.UIAxes4)
                % 显示变换后图像的直方图
                change_histo = imhist(im);
                plot(app.UIAxes2,change_histo)
                app.changedpicture = im;
            end
        end

        % Button pushed function: NegativeBinarizationButton
        function NegativeBinarizationButtonPushed(app, event)
            % 二值化反比操作
            if isequal(app.changedpicture,'')
                msgbox('No pictures','Warning',"help");
            else
                binaimg = im2bw(app.changedpicture);
                im = imcomplement(binaimg);
                imshow(im,'Parent',app.UIAxes4)
                app.changedpicture = im;
            end
        end

        % Clicked callback: PushTool5
        function PushTool5Clicked(app, event)
            run Main_new.mlapp
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ImageMagic';
            app.figure1.Icon = fullfile(pathToMLAPP, 'wxy标识符.png');
            app.figure1.Tag = 'ImageMagic';
            app.Toolbar = uitoolbar(app.figure1);
            app.Toolbar.Tag = '操作';
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tag = '打开图片';
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, '导入.jpg');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, '保存.jpg');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, '退出.jpg');
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.ClickedCallback = createCallbackFcn(app, @PushTool5Clicked, true);
            app.PushTool5.Icon = fullfile(pathToMLAPP, '功能界面图标.png');
            app.UIAxes = uiaxes(app.figure1);
            title(app.UIAxes, 'Original Picture')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.figure1);
            title(app.UIAxes2, 'Changed Histogram')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 255];
            app.UIAxes2.XTick = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.UIAxes2.XTickLabel = {'0'; ''; ''; ''; ''; '50'; ''; ''; ''; ''; '100'; ''; ''; ''; ''; '150'; ''; ''; ''; ''; '200'; ''; ''; ''; ''; '250'};
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.figure1);
            title(app.UIAxes3, 'Original Histogram')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [0 255];
            app.UIAxes3.XTick = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250];
            app.UIAxes3.XTickLabel = {'0'; ''; ''; ''; ''; '50'; ''; ''; ''; ''; '100'; ''; ''; ''; ''; '150'; ''; ''; ''; ''; '200'; ''; ''; ''; ''; '250'};
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.figure1);
            title(app.UIAxes4, 'Preview')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.uibuttongroup4 = uibuttongroup(app.figure1);
            app.uibuttongroup4.Title = 'Basic Function';
            app.uibuttongroup4.Tag = 'uibuttongroup4';
            app.m8 = uibutton(app.uibuttongroup4, 'push');
            app.m8.ButtonPushedFcn = createCallbackFcn(app, @m8ButtonPushed, true);
            app.m8.Tag = 'm8';
            app.m8.Text = 'Left-side-right';
            app.ClipButton = uibutton(app.uibuttongroup4, 'push');
            app.ClipButton.ButtonPushedFcn = createCallbackFcn(app, @ClipButtonPushed, true);
            app.ClipButton.Text = 'Clip';
            app.GrayButton = uibutton(app.uibuttongroup4, 'push');
            app.GrayButton.ButtonPushedFcn = createCallbackFcn(app, @GrayButtonPushed, true);
            app.GrayButton.Text = 'Gray';
            app.BinarizationButton = uibutton(app.uibuttongroup4, 'push');
            app.BinarizationButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizationButtonPushed, true);
            app.BinarizationButton.Text = 'Binarization';
            app.RotationSliderLabel = uilabel(app.uibuttongroup4);
            app.RotationSliderLabel.Text = 'Rotation';
            app.RotationSlider = uislider(app.uibuttongroup4);
            app.RotationSlider.Limits = [-90 90];
            app.RotationSlider.ValueChangedFcn = createCallbackFcn(app, @RotationSliderValueChanged, true);
            app.RotationSlider.MinorTicks = [-90 -80 -70 -60 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.UpsidedownButton = uibutton(app.uibuttongroup4, 'push');
            app.UpsidedownButton.ButtonPushedFcn = createCallbackFcn(app, @UpsidedownButtonPushed, true);
            app.UpsidedownButton.Text = 'Up-side-down';
            app.NegativeBinarizationButton = uibutton(app.uibuttongroup4, 'push');
            app.NegativeBinarizationButton.ButtonPushedFcn = createCallbackFcn(app, @NegativeBinarizationButtonPushed, true);
            app.NegativeBinarizationButton.Text = 'Negative(Binarization)';
            app.ImageEnhancementButtonGroup = uibuttongroup(app.figure1);
            app.ImageEnhancementButtonGroup.Title = 'Image Enhancement';
            app.Label = uilabel(app.ImageEnhancementButtonGroup);
            app.Label.Text = 'Gray-scale transformation';
            app.GrayscaletransformationListBox = uilistbox(app.ImageEnhancementButtonGroup);
            app.GrayscaletransformationListBox.Items = {'', 'Logarithmic', 'Linear-Negative'};
            app.GrayscaletransformationListBox.ValueChangedFcn = createCallbackFcn(app, @GrayscaletransformationListBoxValueChanged, true);
            app.GrayscaletransformationListBox.Value = '';
            app.HistogramequalizationButton = uibutton(app.ImageEnhancementButtonGroup, 'push');
            app.HistogramequalizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramequalizationButtonPushed, true);
            app.HistogramequalizationButton.Text = 'Histogram equalization';
            app.GammaSpinnerLabel = uilabel(app.ImageEnhancementButtonGroup);
            app.GammaSpinnerLabel.Text = 'Gamma';
            app.GammaSpinner = uispinner(app.ImageEnhancementButtonGroup);
            app.GammaSpinner.ValueChangedFcn = createCallbackFcn(app, @GammaSpinnerValueChanged, true);
            app.HistogramLabel = uilabel(app.figure1);
            app.HistogramLabel.Text = 'Histogram';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wxy

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end