classdef zq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BandConversionUIFigure         matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        PushTool2                      matlab.ui.container.toolbar.PushTool
        PushTool3                      matlab.ui.container.toolbar.PushTool
        PushTool4                      matlab.ui.container.toolbar.PushTool
        Panel                          matlab.ui.container.Panel
        TipsTextArea                   matlab.ui.control.TextArea
        TipsTextAreaLabel              matlab.ui.control.Label
        SubtractionRatioButtonGroup    matlab.ui.container.ButtonGroup
        BandOption2EditField           matlab.ui.control.NumericEditField
        BandOption2EditFieldLabel      matlab.ui.control.Label
        BandOption1EditField           matlab.ui.control.NumericEditField
        BandOption1EditFieldLabel      matlab.ui.control.Label
        SAVIParametersButtonGroup      matlab.ui.container.ButtonGroup
        soilregulationparametersEditField  matlab.ui.control.NumericEditField
        soilregulationparametersEditFieldLabel  matlab.ui.control.Label
        TasseledcaptransformSEButtonGroup  matlab.ui.container.ButtonGroup
        degEditField_2                 matlab.ui.control.NumericEditField
        degEditField_2Label            matlab.ui.control.Label
        lenEditField_2                 matlab.ui.control.NumericEditField
        lenEditField_2Label            matlab.ui.control.Label
        nEditField_2                   matlab.ui.control.NumericEditField
        nEditField_2Label              matlab.ui.control.Label
        mEditField_2                   matlab.ui.control.NumericEditField
        mEditField_2Label              matlab.ui.control.Label
        wEditField_2                   matlab.ui.control.NumericEditField
        wEditField_2Label              matlab.ui.control.Label
        rEditField_2                   matlab.ui.control.NumericEditField
        rEditField_2Label              matlab.ui.control.Label
        GeometricneighborhoodDropDown  matlab.ui.control.DropDown
        GeometricneighborhoodDropDownLabel  matlab.ui.control.Label
        OperationButtonGroup           matlab.ui.container.ButtonGroup
        RGB_IHStransformButton         matlab.ui.control.Button
        WaterIndexButton               matlab.ui.control.Button
        SnowIndexButton                matlab.ui.control.Button
        PCAButton                      matlab.ui.control.Button
        SAVIButton                     matlab.ui.control.Button
        DVIButton                      matlab.ui.control.Button
        RVIButton                      matlab.ui.control.Button
        TasseledcaptransformButton     matlab.ui.control.Button
        NDVIButton                     matlab.ui.control.Button
        MultiplicationButton           matlab.ui.control.Button
        RatioButton                    matlab.ui.control.Button
        SubtractionButton              matlab.ui.control.Button
        AdditionButton                 matlab.ui.control.Button
        ProcessedImage                 matlab.ui.control.UIAxes
        OriginalImage                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        I; %原图像
        result; %处理结果
        IValue;  %土壤调节指数
        % 结构元素参数
        r;  
        w;
        m;
        n;
        len;
        st;
        deg;
        OutputFilename; 
        imageSize;
        numBands;
        selectedBand1;
        selectedBand2;
        SE;
        
       
        Pic;


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PushTool
        function LoadClicked(app, event)
            [filename,pathname] = uigetfile({'*.jpg';'*.bmp';'*.tif';'*.png';'*.*'},'Choose an image');
            if isequal(filename,0)||isequal(pathname,0)
                msgbox('You did not select an Image!','Error');
                return;
            else
                    file = fullfile(pathname, filename);
        
                    % 读取图像
                    image = imread(file);
                    app.I = im2double(image);
                     % 将单通道图像转换为RGB图像
                    if size(image, 3) == 1
                        app.I = cat(3, image, image, image);
                    else
                        app.I = image;
                    end
                    % 检查图像通道数
                    if size(app.I, 3) ~= 3
                        msgbox('The image must be an RGB image!', 'Error');
                        return;
                    end
                    
                    imshow(app.I, 'Parent', app.OriginalImage)
                    app.imageSize = size(app.I); % 获取图像的维度信息
                    app.numBands = numel(app.imageSize); % 获取波段数

                 
            end
        end

        % Callback function: PushTool2
        function SaveClicked(app, event)
            [filename,pathname]=uiputfile({'*.jpg';'*.bmp';'*.tif';'*,png'},'Save Image','untitled.tif');
            if ~isequal([filename,pathname],[0,0])
                Mypic=fullfile(pathname,filename);
                imwrite(app.Pic,Mypic);
                msgbox('Save Successfully');
            else
                msgbox('Fail to Save');
            end
        end

        % Callback function: PushTool3
        function ExitClicked(app, event)
            choice=questdlg('Do you want to close？','Close','Yes','No','No');
            switch choice
                case 'Yes'
                    delete(app.BandConversionUIFigure);
                    return;
                case 'No'
                    return;
            end
        end

        % Button pushed function: AdditionButton
        function AdditionButtonPushed(app, event)
            app.Pic = double(app.I(:,:,1)); % 初始化结果矩阵为第一个波段
            % 对每个波段进行累加
            for i = 2:app.numBands
                app.Pic= app.Pic + double(app.I(:,:,i));
            end
            
            app.Pic = app.Pic ./ app.numBands; % 求平均值
            
            imshow(app.Pic,'Parent', app.ProcessedImage)
          
        end

        % Button pushed function: SubtractionButton
        function SubtractionButtonPushed(app, event)
            % 获取用户选择的波段
            band1 = double(app.I(:,:,app.selectedBand1));
            band2 = double(app.I(:,:,app.selectedBand2));
            app.Pic= band1 - band2;
            imshow(app.Pic, 'Parent', app.ProcessedImage)
            
        end

        % Button pushed function: MultiplicationButton
        function MultiplicationButtonPushed(app, event)
% 假设 app.I 是多波段图像，app.numBands 是波段数
            
            % 初始化结果矩阵为第一个波段
            app.Pic = double(app.I(:, :, 1));
            
            % 对每个波段进行累乘
            for i = 2:app.numBands
                app.Pic = app.Pic .* double(app.I(:, :, i));
            end
            
            % 开次方根
            app.Pic = app.Pic .^ (1 / app.numBands);
            
            % 显示处理后的图像
            imshow(app.Pic, 'Parent', app.ProcessedImage);
        end

        % Button pushed function: RatioButton
        function RatioButtonPushed(app, event)
          
            band1 = double(app.I(:,:,app.selectedBand1));
            band2 = double(app.I(:,:,app.selectedBand2));
            app.Pic = band1 ./ band2;
            imshow(app.Pic ,'Parent', app.ProcessedImage)
        
        end

        % Button pushed function: RVIButton
        function RVIButtonPushed(app, event)
            bandNIR = double(app.I(:,:,3));
            bandRed = double(app.I(:,:,2));
            app.Pic= bandNIR ./ bandRed;
            imshow(app.Pic ,'Parent', app.ProcessedImage)
           
        end

        % Button pushed function: WaterIndexButton
        function WaterIndexButtonPushed(app, event)
            %(2 5)Green Red SWIR
            bandGreen = double(app.I(:,:,1));
            bandSWIR = double(app.I(:,:,3));
            app.Pic  = bandGreen + bandSWIR;
            imshow(app.Pic ,'Parent', app.ProcessedImage)
            
        end

        % Button pushed function: SnowIndexButton
        function SnowIndexButtonPushed(app, event)
            %(2 5)
            bandGreen = double(app.I(:,:,1));
            bandSWIR = double(app.I(:,:,3));
            app.Pic= bandGreen - bandSWIR;
            imshow(app.Pic ,'Parent', app.ProcessedImage)
          
        end

        % Button pushed function: NDVIButton
        function NDVIButtonPushed(app, event)
            %(1 4)GreenRedNIR
            bandNIR = double(app.I(:,:,3));
            bandRed = double(app.I(:,:,2));
            app.Pic= (bandNIR - bandRed) ./ (bandNIR + bandRed);
            imshow(app.Pic,'Parent', app.ProcessedImage)
           
        end

        % Button pushed function: DVIButton
        function DVIButtonPushed(app, event)
            %(1 4)
            bandNIR = double(app.I(:,:,3));
            bandRed = double(app.I(:,:,2));
            app.Pic= bandNIR - bandRed;
            imshow(app.Pic ,'Parent', app.ProcessedImage)
            
        end

        % Button pushed function: PCAButton
        function PCAButtonPushed(app, event)
              % 假设 app.I 是多波段图像
            
            % 确认图像数据类型和大小
            if ~isfloat(app.I)
                app.I = double(app.I); % 转换为 double 类型
            end
            
            % 调整数据尺寸
            image_2d = reshape(app.I, [], size(app.I, 3));
            
            % 计算主成分
            numComponents = 3; % 可根据需要调整主成分数量
            coeff = pca(image_2d, 'NumComponents', numComponents);
            
            % 应用主成分到原始图像
            pc_image = image_2d * coeff(:, 1:numComponents);
            
            % 将结果转换回图像形状
            app.Pic = reshape(pc_image, size(app.I, 1), size(app.I, 2), numComponents);
            
            % 显示处理后的图像
            imshow(app.Pic, 'Parent', app.ProcessedImage);
           
        end

        % Button pushed function: RGB_IHStransformButton
        function RGB_IHStransformButtonPushed(app, event)
            ihs_image = rgb2hsv(app.I);% % 将 RGB 图像转换为 IHS 颜色空间
            intensity = ihs_image(:, :, 3);
            hue = ihs_image(:, :, 1);
            saturation = ihs_image(:, :, 2);
            
            transformedImage = cat(3, intensity, hue, saturation);  % Store the transformed image in a new variable
            app.Pic = transformedImage;  % Assign the transformed image to the app property
            imshow(app.Pic, 'Parent', app.ProcessedImage);  % Display the transformed image in UIAxes2
           
        end

        % Value changed function: soilregulationparametersEditField
        function soilregulationparametersEditFieldValue(app, event)
            value = app.soilregulationparametersEditField.Value;
            app.IValue=value;
        end

        % Value changed function: rEditField_2
        function rEditField(app, event)
            value = app.rEditField_2.Value;
            app.r=value;
        end

        % Value changed function: wEditField_2
        function wEditField(app, event)
            value = app.wEditField_2.Value;
            app.w=value;
        end

        % Value changed function: mEditField_2
        function mEditField(app, event)
            value = app.mEditField_2.Value;
            app.m=value;
        end

        % Value changed function: nEditField_2
        function nEditField(app, event)
            value = app.nEditField_2.Value;
            app.n=value;
        end

        % Value changed function: lenEditField_2
        function lenEditField(app, event)
            value = app.lenEditField_2.Value;
            app.len=value;
        end

        % Value changed function: degEditField_2
        function degEditField(app, event)
            value = app.degEditField_2.Value;
            app.deg=value;
        end

        % Button pushed function: TasseledcaptransformButton
        function TasseledcaptransformButtonPushed(app, event)
                switch char(app.st)
                case 'diamond'
                    app.SE = strel("diamond", app.r(1));
                case 'disk'
                    app.SE = strel("disk", app.r(1));
                case 'octagon'
                    app.SE = strel("octagon", app.r(1));
                case 'line'
                    app.SE = strel("line", app.len(1), app.deg(1));
                case 'rectangle'
                    app.SE = strel("rectangle", [app.m(1) app.n(1)]);
                case 'square'
                    app.SE = strel("square", app.w(1));
            end
            app.Pic = imtophat(app.I, app.SE);
            imshow(app.Pic, 'Parent', app.ProcessedImage)
           
        end

        % Button pushed function: SAVIButton
        function SAVIButtonPushed(app, event)

            bandNIR = double(app.I(:,:,3));
            bandRed = double(app.I(:,:,2));
            app.Pic= (bandNIR - bandRed) ./ (bandNIR + bandRed + app.IValue) .* (1 + app.IValue);
            imshow(app.Pic ,'Parent', app.ProcessedImage) 
       
        end

        % Value changed function: BandOption1EditField
        function BandOption1EditFieldValue(app, event)
            value = app.BandOption1EditField.Value;
            app.selectedBand1=value;
        end

        % Value changed function: BandOption2EditField
        function BandOption2EditFieldValue(app, event)
            value = app.BandOption2EditField.Value;
            app.selectedBand2=value;
        end

        % Value changed function: GeometricneighborhoodDropDown
        function GeometricneighborhoodDropDownValueChanged(app, event)
            value = app.GeometricneighborhoodDropDown.Value;
    
            % 初始化可见性状态
            isDiamond = false;
            isDisk = false;
            isOctagon = false;
            isLine = false;
            isRectangle = false;
            isSquare = false;
            
            % 根据所选选项设置可见性状态
            switch value
                case 'diamond'
                    isDiamond = true;
                case 'disk'
                    isDisk = true;
                case 'octagon'
                    isOctagon = true;
                case 'line'
                    isLine = true;
                case 'rectangle'
                    isRectangle = true;
                case 'square'
                    isSquare = true;
            end
            
            % 根据可见性状态设置字段的可见性
            app.rEditField_2.Visible = convertVisibility(isDiamond || isDisk || isOctagon);
            app.lenEditField_2.Visible = convertVisibility(isLine);
            app.degEditField_2.Visible = convertVisibility(isLine);
            app.wEditField_2.Visible = convertVisibility(isSquare);
            app.mEditField_2.Visible = convertVisibility(isRectangle);
            app.nEditField_2.Visible = convertVisibility(isRectangle);
            
            % 自定义函数：将可见性状态转换为可见性字符串
            function visibility = convertVisibility(isVisible)
                if isVisible
                    visibility = 'on';
                else
                    visibility = 'off';
                end
            end
        end

        % Callback function: PushTool4
        function PushTool4Clicked(app, event)
            run Main.mlapp
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BandConversionUIFigure = uifigure('Visible', 'off');
            app.BandConversionUIFigure.Name = 'Band Conversion';
            app.BandConversionUIFigure.Icon = fullfile(pathToMLAPP, 'R-C (6).jpg');
            app.Toolbar = uitoolbar(app.BandConversionUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Import an Image'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @LoadClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, '2组_图像处理软件ImageMagic', '2组_图像处理软件ImageMagic', '导入.jpg');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Save the Processed Image'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @SaveClicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, '2组_图像处理软件ImageMagic', '2组_图像处理软件ImageMagic', '保存.jpg');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Exit'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @ExitClicked, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, '2组_图像处理软件ImageMagic', '2组_图像处理软件ImageMagic', '退出.jpg');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'Return to upper level'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, '2组_图像处理软件ImageMagic', '2组_图像处理软件ImageMagic', '功能界面图标.png');
            app.Panel = uipanel(app.BandConversionUIFigure);
            app.Panel.Title = 'Panel';
            app.OriginalImage = uiaxes(app.Panel);
            title(app.OriginalImage, 'Original Image')
            zlabel(app.OriginalImage, 'Z')
            app.OriginalImage.XTick = [];
            app.OriginalImage.YTick = [];
            app.ProcessedImage = uiaxes(app.Panel);
            title(app.ProcessedImage, 'Processed Image')
            zlabel(app.ProcessedImage, 'Z')
            app.ProcessedImage.XTick = [];
            app.ProcessedImage.YTick = [];
            app.OperationButtonGroup = uibuttongroup(app.Panel);
            app.OperationButtonGroup.Title = 'Operation';
            app.AdditionButton = uibutton(app.OperationButtonGroup, 'push');
            app.AdditionButton.ButtonPushedFcn = createCallbackFcn(app, @AdditionButtonPushed, true);
            app.AdditionButton.Text = 'Addition';
            app.SubtractionButton = uibutton(app.OperationButtonGroup, 'push');
            app.SubtractionButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractionButtonPushed, true);
            app.SubtractionButton.Tooltip = {'Select 2 bands'};
            app.SubtractionButton.Text = 'Subtraction';
            app.RatioButton = uibutton(app.OperationButtonGroup, 'push');
            app.RatioButton.ButtonPushedFcn = createCallbackFcn(app, @RatioButtonPushed, true);
            app.RatioButton.Tooltip = {'Select 2 bands'};
            app.RatioButton.Text = 'Ratio';
            app.MultiplicationButton = uibutton(app.OperationButtonGroup, 'push');
            app.MultiplicationButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicationButtonPushed, true);
            app.MultiplicationButton.Text = 'Multiplication';
            app.NDVIButton = uibutton(app.OperationButtonGroup, 'push');
            app.NDVIButton.ButtonPushedFcn = createCallbackFcn(app, @NDVIButtonPushed, true);
            app.NDVIButton.Tooltip = {'(NIR-Red)/(NIR+Red)'};
            app.NDVIButton.Text = 'NDVI';
            app.TasseledcaptransformButton = uibutton(app.OperationButtonGroup, 'push');
            app.TasseledcaptransformButton.ButtonPushedFcn = createCallbackFcn(app, @TasseledcaptransformButtonPushed, true);
            app.TasseledcaptransformButton.Text = 'Tasseled cap transform';
            app.RVIButton = uibutton(app.OperationButtonGroup, 'push');
            app.RVIButton.ButtonPushedFcn = createCallbackFcn(app, @RVIButtonPushed, true);
            app.RVIButton.Tooltip = {'NIR/Red'};
            app.RVIButton.Text = 'RVI';
            app.DVIButton = uibutton(app.OperationButtonGroup, 'push');
            app.DVIButton.ButtonPushedFcn = createCallbackFcn(app, @DVIButtonPushed, true);
            app.DVIButton.Tooltip = {'NIR-Red'};
            app.DVIButton.Text = 'DVI';
            app.SAVIButton = uibutton(app.OperationButtonGroup, 'push');
            app.SAVIButton.ButtonPushedFcn = createCallbackFcn(app, @SAVIButtonPushed, true);
            app.SAVIButton.Tooltip = {'(NIR - Red) ./ (NIR + Red + L) * (1 + L)'};
            app.SAVIButton.Text = 'SAVI';
            app.PCAButton = uibutton(app.OperationButtonGroup, 'push');
            app.PCAButton.ButtonPushedFcn = createCallbackFcn(app, @PCAButtonPushed, true);
            app.PCAButton.Text = 'PCA';
            app.SnowIndexButton = uibutton(app.OperationButtonGroup, 'push');
            app.SnowIndexButton.ButtonPushedFcn = createCallbackFcn(app, @SnowIndexButtonPushed, true);
            app.SnowIndexButton.Tooltip = {'Green -SWIR'};
            app.SnowIndexButton.Text = 'Snow Index';
            app.WaterIndexButton = uibutton(app.OperationButtonGroup, 'push');
            app.WaterIndexButton.ButtonPushedFcn = createCallbackFcn(app, @WaterIndexButtonPushed, true);
            app.WaterIndexButton.Tooltip = {'Green +SWIR'};
            app.WaterIndexButton.Text = 'Water Index';
            app.RGB_IHStransformButton = uibutton(app.OperationButtonGroup, 'push');
            app.RGB_IHStransformButton.ButtonPushedFcn = createCallbackFcn(app, @RGB_IHStransformButtonPushed, true);
            app.RGB_IHStransformButton.Text = 'RGB_IHS transform';
            app.TasseledcaptransformSEButtonGroup = uibuttongroup(app.Panel);
            app.TasseledcaptransformSEButtonGroup.Title = 'Tasseled cap transform SE';
            app.GeometricneighborhoodDropDownLabel = uilabel(app.TasseledcaptransformSEButtonGroup);
            app.GeometricneighborhoodDropDownLabel.Text = 'Geometric   neighborhood';
            app.GeometricneighborhoodDropDown = uidropdown(app.TasseledcaptransformSEButtonGroup);
            app.GeometricneighborhoodDropDown.Items = {'diamond', 'line', 'disk', 'octagon', 'rectangle', 'square'};
            app.GeometricneighborhoodDropDown.Editable = 'on';
            app.GeometricneighborhoodDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometricneighborhoodDropDownValueChanged, true);
            app.GeometricneighborhoodDropDown.Value = 'diamond';
            app.rEditField_2Label = uilabel(app.TasseledcaptransformSEButtonGroup);
            app.rEditField_2Label.Text = 'r';
            app.rEditField_2 = uieditfield(app.TasseledcaptransformSEButtonGroup, 'numeric');
            app.rEditField_2.ValueChangedFcn = createCallbackFcn(app, @rEditField, true);
            app.wEditField_2Label = uilabel(app.TasseledcaptransformSEButtonGroup);
            app.wEditField_2Label.Text = 'w';
            app.wEditField_2 = uieditfield(app.TasseledcaptransformSEButtonGroup, 'numeric');
            app.wEditField_2.ValueChangedFcn = createCallbackFcn(app, @wEditField, true);
            app.mEditField_2Label = uilabel(app.TasseledcaptransformSEButtonGroup);
            app.mEditField_2Label.Text = 'm';
            app.mEditField_2 = uieditfield(app.TasseledcaptransformSEButtonGroup, 'numeric');
            app.mEditField_2.ValueChangedFcn = createCallbackFcn(app, @mEditField, true);
            app.nEditField_2Label = uilabel(app.TasseledcaptransformSEButtonGroup);
            app.nEditField_2Label.Text = 'n';
            app.nEditField_2 = uieditfield(app.TasseledcaptransformSEButtonGroup, 'numeric');
            app.nEditField_2.ValueChangedFcn = createCallbackFcn(app, @nEditField, true);
            app.lenEditField_2Label = uilabel(app.TasseledcaptransformSEButtonGroup);
            app.lenEditField_2Label.Text = 'len';
            app.lenEditField_2 = uieditfield(app.TasseledcaptransformSEButtonGroup, 'numeric');
            app.lenEditField_2.ValueChangedFcn = createCallbackFcn(app, @lenEditField, true);
            app.degEditField_2Label = uilabel(app.TasseledcaptransformSEButtonGroup);
            app.degEditField_2Label.Text = 'deg';
            app.degEditField_2 = uieditfield(app.TasseledcaptransformSEButtonGroup, 'numeric');
            app.degEditField_2.ValueChangedFcn = createCallbackFcn(app, @degEditField, true);
            app.SAVIParametersButtonGroup = uibuttongroup(app.Panel);
            app.SAVIParametersButtonGroup.Title = 'SAVI Parameters';
            app.soilregulationparametersEditFieldLabel = uilabel(app.SAVIParametersButtonGroup);
            app.soilregulationparametersEditFieldLabel.Text = 'soil regulation parameters';
            app.soilregulationparametersEditField = uieditfield(app.SAVIParametersButtonGroup, 'numeric');
            app.soilregulationparametersEditField.ValueChangedFcn = createCallbackFcn(app, @soilregulationparametersEditFieldValue, true);
            app.SubtractionRatioButtonGroup = uibuttongroup(app.Panel);
            app.SubtractionRatioButtonGroup.Title = 'Subtraction &Ratio';
            app.BandOption1EditFieldLabel = uilabel(app.SubtractionRatioButtonGroup);
            app.BandOption1EditFieldLabel.Text = 'Band Option 1';
            app.BandOption1EditField = uieditfield(app.SubtractionRatioButtonGroup, 'numeric');
            app.BandOption1EditField.ValueChangedFcn = createCallbackFcn(app, @BandOption1EditFieldValue, true);
            app.BandOption2EditFieldLabel = uilabel(app.SubtractionRatioButtonGroup);
            app.BandOption2EditFieldLabel.Text = 'Band Option 2';
            app.BandOption2EditField = uieditfield(app.SubtractionRatioButtonGroup, 'numeric');
            app.BandOption2EditField.ValueChangedFcn = createCallbackFcn(app, @BandOption2EditFieldValue, true);
            app.TipsTextAreaLabel = uilabel(app.Panel);
            app.TipsTextAreaLabel.Text = 'Tips';
            app.TipsTextArea = uitextarea(app.Panel);
            app.TipsTextArea.Value = {'计算植被指数时Red和NIR分别为Band2和Band3；计算其他指数时Green和SWIR分别为Band1和Band3'};
            % Show the figure after all components are created
            app.BandConversionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zq

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BandConversionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BandConversionUIFigure)
        end
    end
end